classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CopyRightText                 matlab.ui.control.Label
        AllPPDPlot                    matlab.ui.control.UIAxes
        TopoPlot                      matlab.ui.control.UIAxes
        SedPlot                       matlab.ui.control.UIAxes
        FrequencyEditFieldLabel       matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        PPDPlot                       matlab.ui.control.UIAxes
        Run                           matlab.ui.control.Button
        BeaconSpecLabel               matlab.ui.control.Label
        GaussPointLabel               matlab.ui.control.Label
        SourceLevelEditFieldLabel     matlab.ui.control.Label
        SourceLevelEditField          matlab.ui.control.NumericEditField
        GPLonUnit                     matlab.ui.control.Label
        GPLatUnit                     matlab.ui.control.Label
        FrequencyUnit                 matlab.ui.control.Label
        SourceLevelUnit               matlab.ui.control.Label
        DepthUnit                     matlab.ui.control.Label
        DepthGridSize                 matlab.ui.control.NumericEditField
        RadiusGridSizeUnit            matlab.ui.control.Label
        RadiusGridSize                matlab.ui.control.NumericEditField
        GridSizeOfDGS                 matlab.ui.control.Label
        DepthOfGDS                    matlab.ui.control.Label
        GridSizeOfRGS                 matlab.ui.control.Label
        RadiusOfRGS                   matlab.ui.control.Label
        LatitudeEditField_2Label      matlab.ui.control.Label
        Lat                           matlab.ui.control.NumericEditField
        LongitudeEditField_2Label     matlab.ui.control.Label
        Lon                           matlab.ui.control.NumericEditField
        LonUnit                       matlab.ui.control.Label
        LatitudeUnit                  matlab.ui.control.Label
        CalculateEditFieldLabel       matlab.ui.control.Label
        CalculateRadius               matlab.ui.control.NumericEditField
        RadiusOfCR                    matlab.ui.control.Label
        CalculateRadiusUint           matlab.ui.control.Label
        DepthOfHD                     matlab.ui.control.Label
        HydeophoneEditFieldLabel      matlab.ui.control.Label
        HydeophoneDepth               matlab.ui.control.NumericEditField
        HydrophoneDepthUnit           matlab.ui.control.Label
        LongitudeEditFieldLabel       matlab.ui.control.Label
        GPLonEditField                matlab.ui.control.NumericEditField
        LatitudeEditField_3Label      matlab.ui.control.Label
        GPLatEditField                matlab.ui.control.NumericEditField
        TitleText                     matlab.ui.control.Label
        MonthofHydrodataSpinnerLabel  matlab.ui.control.Label
        MonthofHydrodataSpinner       matlab.ui.control.Spinner
        SedLegend                     matlab.ui.control.Image
        STOPButton                    matlab.ui.control.Button
        GetResultsButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set Function Path
            path_func = '/home/iyunsu/gbm/SimFunction';
            addpath(path_func);
            % Set Mission Path
            path_mission = '/home/iyunsu/moos-ivp-iysu/missions/lab_10_jake_kasper_baseline/';
            cd(path_func);
            load('sediment.mat','sed_lat','sed_lon','sed_index');
            load('taidb500.mat','X','Y','Z');
            load('m_colormap.mat','m_topo_colormap');
            path_dt = '/home/iyunsu/gbm/Example_121.2_22.08/GbmSearchPoints/37500/';
            global SED_LAT;
            global SED_LON;
            global SED_I;
            global GEO_X;
            global GEO_Y;
            global GEO_Z;
            global PATH_MISSION;
            global PATH_DT;
            global DEPLOY;
            global SED_COLOR;
            
            SED_LAT = sed_lat;
            SED_LON = sed_lon;
            SED_I = sed_index;
            GEO_X = X;
            GEO_Y = Y;
            GEO_Z = Z;
            PATH_MISSION = path_mission;
            PATH_DT = path_dt;
            DEPLOY = 'false';
            
            
            %% ================== Plot Sediment Map ===================== %
            SedColor = [1 1 1; 0.5 0.5 0.5; 1 0.65 0.5; 0.55 0.31 0; ...
                0.7 0.4 0.16; 1 0.8 0.5; 0.9 0.9 0.9];
            SED_COLOR = SedColor;
            SedName = {'Land', 'Rock', 'Coral', 'Gravel', ...
                'Sand', 'Silt', 'Clay'};
            p = pcolor(app.SedPlot,sed_lon,sed_lat,sed_index)
            p.LineStyle = 'none'
            colormap (app.SedPlot,SedColor);
            %             view(app.SedPlot,0, -90);
            %             axis(app.SedPlot, 'equal');
            xlim(app.SedPlot,[118 123]);
            ylim(app.SedPlot,[21 26]);
            
            %% ================== Plot Topography Map =================== %
            
            % Coast line
            Zn = find(Z>0);
            Znan = Z;
            Znan(Zn)=nan;
            Zzero = Z;
            Zzero(Zn)=10;
            % Remove land data
            Z(Z>0)=nan;
            
            % Plot
            contourf(app.TopoPlot,X,Y,-Z/1000,[0:0.5:7],'color',[0.4 0.4 0.4])
            colormap(app.TopoPlot,m_topo_colormap)
            barTopo = colorbar(app.TopoPlot,"westoutside");
            set(barTopo,'YTick',0:1:7)
            barTopo.Label.String = 'Depth(km)';
            set(barTopo, 'YDir', 'reverse' );
            caxis(app.TopoPlot,[0 7]);
            xlim(app.TopoPlot,[118 123]);
            ylim(app.TopoPlot,[21 26]);
            hold (app.TopoPlot,'on')
            contour(app.TopoPlot,X,Y,Zzero,[0 0],'k','LineWidth',2);
            hold (app.TopoPlot,'off')
            
        end

        % Button pushed function: Run
        function RunButtonPushed(app, event)
            % Get Global Data
            global SED_LAT;
            global SED_LON;
            global SED_I;
            global GEO_X;
            global GEO_Y;
            global GEO_Z;
            global PATH_MISSION;
            global PATH_DT;
            global DEPLOY;
            global SED_COLOR;
            global STATS_PPD;
            global GP_LON;
            global GP_LAT;
            global XPOINTS;
            global YPOINTS;
            X = GEO_X;
            Y = GEO_Y;
            Z = GEO_Z;
            
            % Get Value
            getGPLon = app.GPLonEditField.Value;
            GP_LON = getGPLon;
            getGPLat = app.GPLatEditField.Value;
            GP_LAT = getGPLat;
            getSL    = app.SourceLevelEditField;
            getFreq  = app.FrequencyEditField;
            [~ , searchLon,searchLat]=textread([PATH_MISSION,'searchPointCoord.txt'])
            DEPLOY = "true";
            
            % Set Search Region
            searchRectX = [getGPLon-0.025 getGPLon-0.025 getGPLon+0.025 getGPLon+0.025 getGPLon-0.025];
            searchRectY = [getGPLat-0.025 getGPLat+0.025 getGPLat+0.025 getGPLat-0.025 getGPLat-0.025];
            
            % ===================== Topo Plot Zoom in =====================
            TopoXc = round((getGPLon-117)/0.0046,0);
            TopoYc = -round((getGPLat-27)/0.0046,0);
            Cmin = fix(min(min(-Z(TopoYc-12:TopoYc+12,TopoXc-12:TopoXc+12)))/10)*10;
            Cmax = fix(max(max(-Z(TopoYc-12:TopoYc+12,TopoXc-12:TopoXc+12)))/10)*10+10;
            
            % plot coutuor line
            [c,h]=contourf(app.TopoPlot,X(TopoYc-16:TopoYc+16,TopoXc-16:TopoXc+16), ...
                Y(TopoYc-16:TopoYc+16,TopoXc-16:TopoXc+16), ...
                -Z(TopoYc-16:TopoYc+16,TopoXc-16:TopoXc+16), ...
                [0:10:Cmax],'color',[0.4 0.4 0.4],'ShowText','on');
            clabel(c,h,'Color',[0.4 0.4 0.4])
            barTopo = colorbar(app.TopoPlot,"westoutside");
            barTopo.Label.String = 'Depth(km)';
            set(barTopo, 'YDir', 'reverse' );
            caxis(app.TopoPlot,[Cmin Cmax]);
            hold(app.TopoPlot,'on')
            plot(app.TopoPlot,getGPLon,getGPLat,'x', ...
                'MarkerSize',10,'MarkerEdgeColor','y','LineWidth',2)
            plot (app.TopoPlot,searchRectX,searchRectY,'y--')
            
            xlim(app.TopoPlot,[getGPLon-0.045 getGPLon+0.045]);
            ylim(app.TopoPlot,[getGPLat-0.045 getGPLat+0.045]);
            hold(app.TopoPlot,'off')
            
            % ===================== Sed Plot Zoom in ======================
            
            SedXc = round((getGPLon-SED_LON(1))/(SED_LON(2)-SED_LON(1)),0);
            SedYc = round((getGPLat-SED_LAT(1))/(SED_LAT(2)-SED_LAT(1)),0);
            
            p = pcolor(app.SedPlot,SED_LON(SedXc-20:SedXc+20), ...
                SED_LAT(SedYc-20:SedYc+20), ...
                SED_I(SedYc-20:SedYc+20,SedXc-20:SedXc+20))
            p.LineStyle = 'none';
            colormap (app.SedPlot,SED_COLOR);
            caxis(app.SedPlot,[0 6])
            hold(app.SedPlot,"on");
            plot(app.SedPlot,getGPLon,getGPLat,'x', ...
                'MarkerSize',10,'MarkerEdgeColor','y','LineWidth',2)
            plot (app.SedPlot,searchRectX,searchRectY,'y--')
            xlim(app.SedPlot,[getGPLon-0.045 getGPLon+0.045]);
            ylim(app.SedPlot,[getGPLat-0.045 getGPLat+0.045]);
            
            hold(app.SedPlot,'off')
            %            [c,h]=contour(app.SedPlot,X(TopoYc-20:TopoYc+20,TopoXc-20:TopoXc+20), ...
            %                 Y(TopoYc-20:TopoYc+20,TopoXc-20:TopoXc+20), ...
            %                 -Z(TopoYc-20:TopoYc+20,TopoXc-20:TopoXc+20), ...
            %                 [0:10:Cmax],'ShowText','on','Fill','off');
            %             clabel(c,h,'Color',[0.4 0.4 0.4])
            %
            
            % ==================== Plot Search Point ======================
            
            plot3(app.AllPPDPlot, searchLon, searchLat,ones(size(searchLat))*2, ...
                'k.','MarkerSize',10);
            view(app.AllPPDPlot,2)
            xlim(app.AllPPDPlot,[getGPLon-0.045 getGPLon+0.045]);
            ylim(app.AllPPDPlot,[getGPLat-0.045 getGPLat+0.045]);
            
            
            Var = 0;
            moosPreLabel = 0;
            sumPPD = zeros(1468,1468);
            iDivisor = zeros(1468, 1468);
            
            %
            while (DEPLOY == "true")
                [moosName, moosVal] = textread('/home/iyunsu/gbm/location.txt', '%s %s', 'delimiter', ' ')
                moosLabel = str2double(moosVal(1));
                moosType = char(moosVal(2));
                
                
                if moosLabel ~= moosPreLabel && moosPreLabel ~= 0
                    
                    hold(app.AllPPDPlot,'on')
                    if moosType == 'benign'
                        plot3(app.AllPPDPlot, searchLon(moosLabel), searchLat(moosLabel),2,'k+','LineWidth',3,'MarkerSize',10);
                    end
                    if moosType == 'hazard'
                        plot3(app.AllPPDPlot, searchLon(moosLabel), searchLat(moosLabel),2,'r+','LineWidth',3,'MarkerSize',10);
                        
                        % load GBM results
                        cd([PATH_DT,num2str(searchLon(moosLabel)),'_',num2str(searchLat(moosLabel)),'_5m'])
                        lon = load([num2str(searchLat(moosLabel)),'_',num2str(searchLon(moosLabel)),'_16dir_lon.txt']);
                        lat = load([num2str(searchLat(moosLabel)),'_',num2str(searchLon(moosLabel)),'_16dir_lat.txt']);
                        app.Lon.Value = searchLon(moosLabel);
                        app.Lat.Value = searchLat(moosLabel);
                        
                        load('TLraPDra.mat');
                        
                        grayPPD = flipud(im2double(rgb2gray(imread(['PD5mGray.png']))));
                        grayPPD = 1-grayPPD;
                        
                        % plot PPD of the latest datected point
                        newPPDPlot = pcolor(app.PPDPlot,[lon; lon(1,:)],[lat; lat(1,:)],[PD150mAz; PD150mAz(1,:)]);
                        newPPDPlot.LineStyle = 'none';
                        newPPDPlot.FaceColor = 'interp';
                        colormap(app.PPDPlot,"parula")
                        caxis(app.PPDPlot, [0 1])
                        axis(app.PPDPlot,'equal')
                        
                        %% plot PPD static results
                        
                        % delete the jpg data out of frame
                        [xFrame, yFrame] = find(round(grayPPD,4) == 0.8510);
                        grayPPD = grayPPD(min(xFrame)+1:max(xFrame)-1,min(yFrame)+1:max(yFrame)-1);
                        
                        % Average the data
                        sumPPD = sumPPD + grayPPD;
                        isNotZero = find(grayPPD~=0);
                        iDivisor(isNotZero) = iDivisor(isNotZero) +1;
                        statsPPD = sumPPD./iDivisor;
                        
                        xPoints = [getGPLon-0.045: 0.09/(length(statsPPD)-1):getGPLon+0.045];
                        yPoints = [getGPLat-0.045: 0.09/(length(statsPPD)-1):getGPLat+0.045]';
                        XPOINTS = xPoints;
                        YPOINTS = yPoints;
                        
                        statsPPDPlot = pcolor(app.AllPPDPlot,xPoints,yPoints,statsPPD);
                        statsPPDPlot.LineStyle = 'none';
                        statsPPDPlot.FaceColor = 'interp';
                        colormap(app.AllPPDPlot,"parula")
                        caxis(app.AllPPDPlot, [0 1])
                        
                        STATS_PPD = statsPPD;
                        
                    end
                    hold(app.AllPPDPlot,'off')
                end
                moosPreLabel = moosLabel;
                pause(1);
                
            end


        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            global DEPLOY;
            DEPLOY = "false";
        end

        % Button pushed function: GetResultsButton
        function GetResultsButtonPushed(app, event)
            
            global GP_LON;
            global GP_LAT;
            global XPOINTS;
            global YPOINTS;
            global STATS_PPD;
            global DEPLOY;
            DEPLOY = 'false';
            xPointsPer10m = [GP_LON-0.045:0.0001:GP_LON+0.045];
            yPointsPer10m = [GP_LAT-0.045:0.0001:GP_LAT+0.045];
            
            % Get STATS_PPD per 10 m data
            [lonGrid, latGrid] = meshgrid(xPointsPer10m,yPointsPer10m);
            [gridCol, gridRow] = size(lonGrid);
            PPDper10mLineArray = interp2(XPOINTS,YPOINTS,STATS_PPD,reshape(lonGrid,[1,gridCol*gridRow]),reshape(latGrid,[1,gridCol*gridRow]));
            PPDper10m = reshape(PPDper10mLineArray,[gridCol, gridRow])
            
            % Average Filter (200m)
            windoeSize = 20;
            averageWindow = fspecial('average',[windoeSize,windoeSize]);
            PPDper10mAfter200mAve = imfilter(PPDper10m,averageWindow);
            PPDper200m = PPDper10mAfter200mAve(1:windoeSize:end,1:windoeSize:end);
            latGrid200m = latGrid(1:windoeSize:end,1:windoeSize:end);
            lonGrid200m = lonGrid(1:windoeSize:end,1:windoeSize:end);
            
            [maxPPDCol maxPPDRow] = find(PPDper200m == max(max(PPDper200m)));
            lonMaxPPD = lonGrid200m(maxPPDCol,maxPPDRow);
            latMaxPPD = latGrid200m(maxPPDCol,maxPPDRow);
            
            hold(app.AllPPDPlot,'on')
            rectRadius = 0.0025;
            rectLonMaxPPD = [lonMaxPPD-rectRadius lonMaxPPD-rectRadius ...
                lonMaxPPD+rectRadius lonMaxPPD+rectRadius lonMaxPPD-rectRadius]
            rectLatMaxPPD = [latMaxPPD-rectRadius latMaxPPD+rectRadius ...
                latMaxPPD+rectRadius latMaxPPD-rectRadius latMaxPPD-rectRadius]
            patch(app.AllPPDPlot,rectLonMaxPPD,rectLatMaxPPD,'red','FaceAlpha',0.5)
            hold(app.AllPPDPlot,'off')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CopyRightText = uilabel(app.UIFigure);
            app.CopyRightText.Text = '©2020 by I Yun Su.';
            app.AllPPDPlot = uiaxes(app.UIFigure);
            title(app.AllPPDPlot, 'Predictive Probability of Detection')
            xlabel(app.AllPPDPlot, 'Longitude (°E)')
            ylabel(app.AllPPDPlot, 'Latitude (°N)')
            app.TopoPlot = uiaxes(app.UIFigure);
            title(app.TopoPlot, 'Topographic Map')
            xlabel(app.TopoPlot, '')
            ylabel(app.TopoPlot, '')
            app.TopoPlot.XTick = [];
            app.TopoPlot.YTick = [];
            app.SedPlot = uiaxes(app.UIFigure);
            title(app.SedPlot, 'Sediment Map')
            xlabel(app.SedPlot, '')
            ylabel(app.SedPlot, '')
            app.SedPlot.XTick = [];
            app.SedPlot.XTickLabel = '';
            app.SedPlot.YTick = [];
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Tag = 'Beacon Spec';
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Limits = [0 Inf];
            app.FrequencyEditField.ValueDisplayFormat = '%.0f';
            app.FrequencyEditField.Tag = 'Beacon Spec';
            app.FrequencyEditField.Tooltip = {''};
            app.FrequencyEditField.Value = 37500;
            app.PPDPlot = uiaxes(app.UIFigure);
            title(app.PPDPlot, 'The Latest Detected Point PPD')
            xlabel(app.PPDPlot, '')
            ylabel(app.PPDPlot, '')
            app.PPDPlot.XTick = [];
            app.PPDPlot.YTick = [];
            app.Run = uibutton(app.UIFigure, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Run.Text = 'Run';
            app.BeaconSpecLabel = uilabel(app.UIFigure);
            app.BeaconSpecLabel.Tag = 'Beacon Spec';
            app.BeaconSpecLabel.Text = 'Beacon Spec';
            app.GaussPointLabel = uilabel(app.UIFigure);
            app.GaussPointLabel.Text = 'Gauss Point';
            app.SourceLevelEditFieldLabel = uilabel(app.UIFigure);
            app.SourceLevelEditFieldLabel.Tag = 'Beacon Spec';
            app.SourceLevelEditFieldLabel.Text = 'Source Level';
            app.SourceLevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SourceLevelEditField.Limits = [0 Inf];
            app.SourceLevelEditField.ValueDisplayFormat = '%.1f';
            app.SourceLevelEditField.Tag = 'Beacon Spec';
            app.SourceLevelEditField.Value = 160;
            app.GPLonUnit = uilabel(app.UIFigure);
            app.GPLonUnit.Text = '°E';
            app.GPLatUnit = uilabel(app.UIFigure);
            app.GPLatUnit.Text = '°N';
            app.FrequencyUnit = uilabel(app.UIFigure);
            app.FrequencyUnit.Text = 'Hz';
            app.SourceLevelUnit = uilabel(app.UIFigure);
            app.SourceLevelUnit.Text = 'dB';
            app.DepthUnit = uilabel(app.UIFigure);
            app.DepthUnit.Text = 'm';
            app.DepthGridSize = uieditfield(app.UIFigure, 'numeric');
            app.DepthGridSize.Editable = 'off';
            app.DepthGridSize.Value = 10;
            app.RadiusGridSizeUnit = uilabel(app.UIFigure);
            app.RadiusGridSizeUnit.Text = 'm';
            app.RadiusGridSize = uieditfield(app.UIFigure, 'numeric');
            app.RadiusGridSize.Editable = 'off';
            app.RadiusGridSize.Value = 10;
            app.GridSizeOfDGS = uilabel(app.UIFigure);
            app.GridSizeOfDGS.Text = 'Grid Size';
            app.DepthOfGDS = uilabel(app.UIFigure);
            app.DepthOfGDS.Text = 'Depth';
            app.GridSizeOfRGS = uilabel(app.UIFigure);
            app.GridSizeOfRGS.Text = 'Grid Size';
            app.RadiusOfRGS = uilabel(app.UIFigure);
            app.RadiusOfRGS.Text = 'Radius';
            app.LatitudeEditField_2Label = uilabel(app.UIFigure);
            app.LatitudeEditField_2Label.Text = 'Latitude';
            app.Lat = uieditfield(app.UIFigure, 'numeric');
            app.Lat.ValueDisplayFormat = '%.3f';
            app.Lat.Editable = 'off';
            app.LongitudeEditField_2Label = uilabel(app.UIFigure);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.Lon = uieditfield(app.UIFigure, 'numeric');
            app.Lon.ValueDisplayFormat = '%.3f';
            app.Lon.Editable = 'off';
            app.LonUnit = uilabel(app.UIFigure);
            app.LonUnit.Text = '°E';
            app.LatitudeUnit = uilabel(app.UIFigure);
            app.LatitudeUnit.Text = '°N';
            app.CalculateEditFieldLabel = uilabel(app.UIFigure);
            app.CalculateEditFieldLabel.Text = 'Calculate';
            app.CalculateRadius = uieditfield(app.UIFigure, 'numeric');
            app.CalculateRadius.Editable = 'off';
            app.CalculateRadius.Value = 5000;
            app.RadiusOfCR = uilabel(app.UIFigure);
            app.RadiusOfCR.Text = 'Radius';
            app.CalculateRadiusUint = uilabel(app.UIFigure);
            app.CalculateRadiusUint.Text = 'm';
            app.DepthOfHD = uilabel(app.UIFigure);
            app.DepthOfHD.Text = 'Depth';
            app.HydeophoneEditFieldLabel = uilabel(app.UIFigure);
            app.HydeophoneEditFieldLabel.Text = 'Hydeophone';
            app.HydeophoneDepth = uieditfield(app.UIFigure, 'numeric');
            app.HydeophoneDepth.Editable = 'off';
            app.HydeophoneDepth.Value = 5;
            app.HydrophoneDepthUnit = uilabel(app.UIFigure);
            app.HydrophoneDepthUnit.Text = 'm';
            app.LongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudeEditFieldLabel.Tag = 'Beacon Spec';
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.GPLonEditField = uieditfield(app.UIFigure, 'numeric');
            app.GPLonEditField.Limits = [0 Inf];
            app.GPLonEditField.ValueDisplayFormat = '%.3f';
            app.GPLonEditField.Tag = 'Beacon Spec';
            app.GPLonEditField.Tooltip = {''};
            app.GPLonEditField.Value = 121.19;
            app.LatitudeEditField_3Label = uilabel(app.UIFigure);
            app.LatitudeEditField_3Label.Tag = 'Beacon Spec';
            app.LatitudeEditField_3Label.Text = 'Latitude';
            app.GPLatEditField = uieditfield(app.UIFigure, 'numeric');
            app.GPLatEditField.Limits = [0 Inf];
            app.GPLatEditField.ValueDisplayFormat = '%.3f';
            app.GPLatEditField.Tag = 'Beacon Spec';
            app.GPLatEditField.Tooltip = {''};
            app.GPLatEditField.Value = 22.065;
            app.TitleText = uilabel(app.UIFigure);
            app.TitleText.Text = 'Flight Recorder Detection Results';
            app.MonthofHydrodataSpinnerLabel = uilabel(app.UIFigure);
            app.MonthofHydrodataSpinnerLabel.Text = 'Month of Hydro-data';
            app.MonthofHydrodataSpinner = uispinner(app.UIFigure);
            app.MonthofHydrodataSpinner.Limits = [1 12];
            app.MonthofHydrodataSpinner.Value = 7;
            app.SedLegend = uiimage(app.UIFigure);
            app.SedLegend.ImageSource = 'legend.png';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.GetResultsButton = uibutton(app.UIFigure, 'push');
            app.GetResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GetResultsButtonPushed, true);
            app.GetResultsButton.Text = 'Get Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end